classdef UI_Clerk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ButtonGroup             matlab.ui.container.ButtonGroup
        open_account            matlab.ui.control.ToggleButton
        close_account           matlab.ui.control.ToggleButton
        deposit                 matlab.ui.control.ToggleButton
        withdraw                matlab.ui.control.ToggleButton
        transfer                matlab.ui.control.ToggleButton
        change_password         matlab.ui.control.ToggleButton
        IdLabel                 matlab.ui.control.Label
        Identity_Input          matlab.ui.control.NumericEditField
        PasswordLabel           matlab.ui.control.Label
        PW_Input                matlab.ui.control.EditField
        Confirm_open_account    matlab.ui.control.Button
        Confirm_close_account   matlab.ui.control.Button
        Confirm_deposit_money   matlab.ui.control.Button
        Confirm_withdraw_money  matlab.ui.control.Button
        Message_Feedback        matlab.ui.control.Label
        AccountNoLabel          matlab.ui.control.Label
        Account_Input           matlab.ui.control.NumericEditField
        NewPasswordLabel        matlab.ui.control.Label
        New_PW_Input            matlab.ui.control.EditField
        Confirm_change_pw       matlab.ui.control.Button
        AmountLabel             matlab.ui.control.Label
        Amount_Input            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        x_edit_box=103;
        w_edit_box=100;
        h_edit_box=22;
        y_1st_edit_box=367; % Description
        y_2nd_edit_box=334; % Description
        y_3rd_edit_box=301;
        y_4th_edit_box=268;
    end
    
    methods (Access = private)
        
        function results = Clear_all_components(app)
            % invisiblize all changable ui components
            app.Identity_Input.Visible='off';
            app.PW_Input.Visible=0;
            app.Account_Input.Visible=0;
            app.Amount_Input.Visible=0;
            app.New_PW_Input.Visible=0;
            app.Confirm_open_account.Visible=0;
            app.Confirm_close_account.Visible=0;
            app.Confirm_deposit_money.Visible=0;
            app.Confirm_withdraw_money.Visible=0;
            app.Confirm_change_pw.Visible=0;
            app.IdLabel.Visible=0;
            app.PasswordLabel.Visible=0;
            app.AccountNoLabel.Visible=0;
            app.AmountLabel.Visible=0;
            app.NewPasswordLabel.Visible=0;
            
            
            app.Message_Feedback.Visible=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             Switch UI
            app.Clear_all_components();
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,app.open_account.Text)
                'Open account'
                app.Identity_Input.Visible=1;
                app.IdLabel.Visible=1;
                app.Identity_Input.Position(2)=app.y_1st_edit_box;
                app.IdLabel.Position(2)=app.y_1st_edit_box;
                
                app.PW_Input.Visible=1;
                app.PasswordLabel.Visible=1;
                app.PW_Input.Position(2)=app.y_2nd_edit_box;
                app.PasswordLabel.Position(2)=app.y_2nd_edit_box;

                
                app.Confirm_open_account.Visible=1;

            end
            if strcmp(selectedButton.Text,app.close_account.Text)
                'Close account'
                
                app.Identity_Input.Visible=1;
                app.IdLabel.Visible=1;
                app.Identity_Input.Position(2)=app.y_1st_edit_box;
                app.IdLabel.Position(2)=app.y_1st_edit_box;
                
                app.PW_Input.Visible=1;
                app.PasswordLabel.Visible=1;
                app.PW_Input.Position(2)=app.y_2nd_edit_box;
                app.PasswordLabel.Position(2)=app.y_2nd_edit_box;
                
                app.Account_Input.Visible=1;
                app.AccountNoLabel.Visible=1;
                app.Account_Input.Position(2)= app.y_3rd_edit_box;
                app.AccountNoLabel.Position(2)=app.y_3rd_edit_box;
                
                app.Confirm_close_account.Visible=1;
            end
            if strcmp(selectedButton.Text,app.deposit.Text)
                'Deposit'
                app.AccountNoLabel.Visible=1;
                app.Account_Input.Visible=1;
                app.Account_Input.Position(2)=app.y_1st_edit_box;
                app.AccountNoLabel.Position(2)=app.y_1st_edit_box;
                
                app.AmountLabel.Visible=1;
                app.Amount_Input.Visible=1;
                app.Amount_Input.Position(2)=app.y_2nd_edit_box;
                app.AmountLabel.Position(2)=app.y_2nd_edit_box;
                
                app.Confirm_deposit_money.Visible=1;
                app.Confirm_deposit_money.Position(2)=app.y_3rd_edit_box;
                
            end
            if strcmp(selectedButton.Text,app.withdraw.Text)
                'Withdraw'
                
                app.AccountNoLabel.Visible=1;
                app.Account_Input.Visible=1;
                app.Account_Input.Position(2)=app.y_1st_edit_box;
                app.AccountNoLabel.Position(2)=app.y_1st_edit_box;
                
                app.PW_Input.Visible=1;
                app.PasswordLabel.Visible=1;
                app.PW_Input.Position(2)=app.y_2nd_edit_box;
                app.PasswordLabel.Position(2)=app.y_2nd_edit_box;
                
                app.AmountLabel.Visible=1;
                app.Amount_Input.Visible=1;
                app.Amount_Input.Position(2)=app.y_3rd_edit_box;
                app.AmountLabel.Position(2)=app.y_3rd_edit_box;
                
                app.Confirm_withdraw_money.Visible=1;
                
%                 
            end
            if strcmp(selectedButton.Text,app.transfer.Text)
                'Transfer'
            end
            if strcmp(selectedButton.Text,app.change_password.Text)
                'Change Password'
                
                app.AccountNoLabel.Visible=1;
                app.Account_Input.Visible=1;
                app.Account_Input.Position(2)=app.y_1st_edit_box;
                app.AccountNoLabel.Position(2)=app.y_1st_edit_box;
                
                app.PW_Input.Visible=1;
                app.PasswordLabel.Visible=1;
                app.PW_Input.Position(2)=app.y_2nd_edit_box;
                app.PasswordLabel.Position(2)=app.y_2nd_edit_box;
                
                app.New_PW_Input.Visible=1;
                app.NewPasswordLabel.Visible=1;
                app.New_PW_Input.Position(2)=app.y_3rd_edit_box;
                app.NewPasswordLabel.Position(2)=app.y_3rd_edit_box;

                app.Confirm_change_pw.Visible=1;

            end
            
        end

        % Button pushed function: Confirm_open_account
        function Confirm_open_accountButtonPushed(app, event)
            identity_no=app.Identity_Input.Value;
            pw=app.PW_Input.Value;
            if identity_no==0
                app.Message_Feedback.Text='Id cannot be emtpy';
            else
                if pw==""
                    app.Message_Feedback.Text='Password cannot be emtpy';
                else
                    app.Message_Feedback.Text="";
                    bs=Banking_system;
                    account_id=bs.addAccount(identity_no,pw);
                    result=bs.addCard(identity_no,account_id,pw);
                    if result
                        app.Message_Feedback.Text=['Account added successfully. ' newline 'Account No. ' num2str(result)];
                    else
                        app.Message_Feedback.Text='Account added Failed';
                    end
                end
            end
            app.Message_Feedback.Visible=1;
            app.Message_Feedback.Position=[243,367,198,42];
            
        end

        % Button pushed function: Confirm_close_account
        function Confirm_close_accountButtonPushed(app, event)
            identity_no=app.Identity_Input.Value;
            pw=app.PW_Input.Value;
            account_id=app.Account_Input.Value;
            app.Message_Feedback.Visible=0;
            if identity_no==0
                app.Message_Feedback.Text='Id cannot be emtpy';
            else
                if pw==""
                    app.Message_Feedback.Text='Password cannot be emtpy';
                else
                    app.Message_Feedback.Text='Account removed Failed';
                    bs=Banking_system;
                    if bs.checkPW(account_id,pw)
                        result=bs.removeAccount(identity_no,account_id);
                        if result
                            app.Message_Feedback.Text='Account removed successfully.';
                        end
                    end
                end
            end
            app.Message_Feedback.Visible=1;
            app.Message_Feedback.Position=[243,367,198,42];
            
        end

        % Button pushed function: Confirm_change_pw
        function Confirm_change_pwButtonPushed(app, event)
            pw=app.PW_Input.Value;
            account_id=app.Account_Input.Value;
            new_pw=app.New_PW_Input.Value;
            app.Message_Feedback.Visible=0;

            if account_id==0
                app.Message_Feedback.Text='Id cannot be emtpy';
            else
                if pw=="" || new_pw==""
                    app.Message_Feedback.Text='Password cannot be emtpy';
                else
                    bs=Banking_system;
                    app.Message_Feedback.Text='Password change Failed';
                    if bs.checkPW(account_id,pw)
                        bs.changePassword(account_id,new_pw);
                        app.Message_Feedback.Text='Password changed successfully.';
                    end
                end
            end
            app.Message_Feedback.Visible=1;
            app.Message_Feedback.Position=[243,367,198,42];
        end

        % Button pushed function: Confirm_deposit_money
        function Confirm_deposit_moneyButtonPushed(app, event)
            account_id=app.Account_Input.Value;
            amount=app.Amount_Input.Value;
            app.Message_Feedback.Text='Money add failed';
            if account_id==0
                app.Message_Feedback.Text='Account cannot be emtpy';
            else
                if amount<=0
                    app.Message_Feedback.Text='Amount invalid';
                else
                    bs=Banking_system;
                    if bs.deposit(account_id,amount)                   
                        app.Message_Feedback.Text='Money added successfully';
                    end
                end
            end
            app.Message_Feedback.Visible=1;
            app.Message_Feedback.Position=[243,367,198,42];
        end

        % Button pushed function: Confirm_withdraw_money
        function Confirm_withdraw_moneyButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.open_account = uitogglebutton(app.ButtonGroup);
            app.open_account.Text = 'Open Account';
            app.open_account.Value = true;
            app.close_account = uitogglebutton(app.ButtonGroup);
            app.close_account.Text = 'Close Account';
            app.deposit = uitogglebutton(app.ButtonGroup);
            app.deposit.Text = 'Deposit';
            app.withdraw = uitogglebutton(app.ButtonGroup);
            app.withdraw.Text = 'Withdraw';
            app.transfer = uitogglebutton(app.ButtonGroup);
            app.transfer.Text = 'Transfer';
            app.change_password = uitogglebutton(app.ButtonGroup);
            app.change_password.Text = 'Change Password';
            app.IdLabel = uilabel(app.UIFigure);
            app.IdLabel.Text = 'Id.';
            app.Identity_Input = uieditfield(app.UIFigure, 'numeric');
            app.PasswordLabel = uilabel(app.UIFigure);
            app.PasswordLabel.Text = 'Password';
            app.PW_Input = uieditfield(app.UIFigure, 'text');
            app.Confirm_open_account = uibutton(app.UIFigure, 'push');
            app.Confirm_open_account.ButtonPushedFcn = createCallbackFcn(app, @Confirm_open_accountButtonPushed, true);
            app.Confirm_open_account.Text = 'Confirm';
            app.Confirm_close_account = uibutton(app.UIFigure, 'push');
            app.Confirm_close_account.ButtonPushedFcn = createCallbackFcn(app, @Confirm_close_accountButtonPushed, true);
            app.Confirm_close_account.Visible = 'off';
            app.Confirm_close_account.Text = 'Confirm';
            app.Confirm_deposit_money = uibutton(app.UIFigure, 'push');
            app.Confirm_deposit_money.ButtonPushedFcn = createCallbackFcn(app, @Confirm_deposit_moneyButtonPushed, true);
            app.Confirm_deposit_money.Visible = 'off';
            app.Confirm_deposit_money.Text = 'Confirm';
            app.Confirm_withdraw_money = uibutton(app.UIFigure, 'push');
            app.Confirm_withdraw_money.ButtonPushedFcn = createCallbackFcn(app, @Confirm_withdraw_moneyButtonPushed, true);
            app.Confirm_withdraw_money.Visible = 'off';
            app.Confirm_withdraw_money.Text = 'Confirm';
            app.Message_Feedback = uilabel(app.UIFigure);
            app.Message_Feedback.Visible = 'off';
            app.Message_Feedback.Text = 'Identity No cannot be empty';
            app.AccountNoLabel = uilabel(app.UIFigure);
            app.AccountNoLabel.Visible = 'off';
            app.AccountNoLabel.Text = 'Account No.';
            app.Account_Input = uieditfield(app.UIFigure, 'numeric');
            app.Account_Input.Visible = 'off';
            app.NewPasswordLabel = uilabel(app.UIFigure);
            app.NewPasswordLabel.Visible = 'off';
            app.NewPasswordLabel.Text = 'New Password';
            app.New_PW_Input = uieditfield(app.UIFigure, 'text');
            app.New_PW_Input.Visible = 'off';
            app.Confirm_change_pw = uibutton(app.UIFigure, 'push');
            app.Confirm_change_pw.ButtonPushedFcn = createCallbackFcn(app, @Confirm_change_pwButtonPushed, true);
            app.Confirm_change_pw.Visible = 'off';
            app.Confirm_change_pw.Text = 'Confirm';
            app.AmountLabel = uilabel(app.UIFigure);
            app.AmountLabel.Visible = 'off';
            app.AmountLabel.Text = 'Amount';
            app.Amount_Input = uieditfield(app.UIFigure, 'numeric');
            app.Amount_Input.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Clerk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end